classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        DanewejciowePanel               matlab.ui.container.Panel
        iterEditField                   matlab.ui.control.NumericEditField
        iterEditFieldLabel              matlab.ui.control.Label
        NEditField                      matlab.ui.control.NumericEditField
        NEditFieldLabel                 matlab.ui.control.Label
        x_maxEditField                  matlab.ui.control.NumericEditField
        x_maxLabel                      matlab.ui.control.Label
        x_minEditField                  matlab.ui.control.NumericEditField
        x_minLabel                      matlab.ui.control.Label
        dimEditField                    matlab.ui.control.NumericEditField
        dimEditFieldLabel               matlab.ui.control.Label
        IloiteracjiLabel                matlab.ui.control.Label
        IloagentwLabel                  matlab.ui.control.Label
        OgraniczeniegrneLabel           matlab.ui.control.Label
        OgraniczeniedolneLabel          matlab.ui.control.Label
        LiczbazmiennychLabel            matlab.ui.control.Label
        ButtonGroup                     matlab.ui.container.ButtonGroup
        maksymalizacjaButton            matlab.ui.control.RadioButton
        minimalizacjaButton             matlab.ui.control.RadioButton
        ObliczButton                    matlab.ui.control.Button
        Image                           matlab.ui.control.Image
        ALGORYTMSZAREGOWILKALabel       matlab.ui.control.Label
        UITable2                        matlab.ui.control.Table
        OptymalnywynikEditFieldLabel_2  matlab.ui.control.Label
        OptymalnywynikEditField         matlab.ui.control.NumericEditField
        OptymalnywynikEditFieldLabel    matlab.ui.control.Label
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    methods (Access = public)
        % function o = F7(x)
        %     F2 = 1;
        %     one = 0;
        %     two = 1;
        %     for i = 1:length(x)
        %         one = one + (x(i)^2/4000);
        %         two = two * (cos(x(i)/sqrt(i)));
        %     end
        %     o = F2 + one + two;
        % end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ObliczButton
        function ObliczButtonPushed(app, event)
            
            agents_num = app.NEditField.Value;
            iterations = app.iterEditField.Value;
            lb=app.x_minEditField.Value; 
            ub=app.x_maxEditField.Value;
            dim=app.dimEditField.Value;


            %   W tym miejscu przypisujemy funkcję do obliczania.
            %   
            %
            %
            fun=@hyper_ellipse;
            
            %cyferki do testow
            % agents_num = 30;
            % iterations = 1000;
            % lb=-600;
            % ub=600;
            % dim=100;

            [best_score, best_pos, convergence_plot] = gray_wolf_opt(fun, dim, lb, ub, agents_num, iterations);

            %dziala, nareszczie
            %
            % w nienawisci do Matlaba zostalem wychowany
            %w mym domu Matlab wiec bedzie jebany
            %bo ten jezyk jest kurwa niedojebany


            x = -10:0.1:10;
            y = x;
            l = length(x);
            z = zeros(length(x), length(y));
            for i = 1:l
                for j=1:l
                    z(i,j) = fun([x(i), y(j)]);
                end
            end
            plot(app.UIAxes,1,2)
            surfc(app.UIAxes,x,y,z, 'LineStyle', 'none');
            %sukces znowu to kurestwo zadzialalo xD
  
            plot(app.UIAxes2,1,2);
            semilogy(app.UIAxes2,convergence_plot,'Color','r')
            
            %to co zakomentowane to mozna w razie potrzeby probowac recznie ustawic
            %chyba ustawilem dobrze ale zostawiam to czego pewny nie jestem
            %zakomentowane wrazie w jakby trzeba bylo poprawic zeby bylo
            %wiadomo co
            axis(app.UIAxes2,"tight")
            %box(on)
       
            legend(app.UIAxes2,'GWO')

            app.OptymalnywynikEditField.Value = best_score;
            app.UITable2.Data = best_pos;


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Przestrzeń parametrów')
            xlabel(app.UIAxes, 'x_1')
            ylabel(app.UIAxes, 'x_2''')
            zlabel(app.UIAxes, 'F( x_1 , x_2 )')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Przestrzeń objektowa')
            xlabel(app.UIAxes2, 'Iteracja')
            ylabel(app.UIAxes2, 'Uzyskane wyniki')
            zlabel(app.UIAxes2, 'Z')
            app.OptymalnywynikEditFieldLabel = uilabel(app.UIFigure);
            app.OptymalnywynikEditFieldLabel.Text = 'Optymalny wynik';
            app.OptymalnywynikEditField = uieditfield(app.UIFigure, 'numeric');
            app.OptymalnywynikEditFieldLabel_2 = uilabel(app.UIFigure);
            app.OptymalnywynikEditFieldLabel_2.Text = 'Optymalne pozycje';
            app.UITable2 = uitable(app.UIFigure);
            app.UITable2.ColumnName = {'Pozycja'};
            app.UITable2.RowName = {};
            app.ALGORYTMSZAREGOWILKALabel = uilabel(app.UIFigure);
            app.ALGORYTMSZAREGOWILKALabel.Text = 'ALGORYTM SZAREGO WILKA';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'assets', 'profesor-rafal-wilczur.jpg');
            app.DanewejciowePanel = uipanel(app.UIFigure);
            app.DanewejciowePanel.Title = 'Dane wejściowe';
            app.ObliczButton = uibutton(app.DanewejciowePanel, 'push');
            app.ObliczButton.ButtonPushedFcn = createCallbackFcn(app, @ObliczButtonPushed, true);
            app.ObliczButton.Text = 'Oblicz';
            app.ButtonGroup = uibuttongroup(app.DanewejciowePanel);
            app.minimalizacjaButton = uiradiobutton(app.ButtonGroup);
            app.minimalizacjaButton.Text = 'minimalizacja';
            app.minimalizacjaButton.Value = true;
            app.maksymalizacjaButton = uiradiobutton(app.ButtonGroup);
            app.maksymalizacjaButton.Text = 'maksymalizacja';
            app.LiczbazmiennychLabel = uilabel(app.DanewejciowePanel);
            app.LiczbazmiennychLabel.Text = 'Liczba zmiennych';
            app.OgraniczeniedolneLabel = uilabel(app.DanewejciowePanel);
            app.OgraniczeniedolneLabel.Text = 'Ograniczenie dolne';
            app.OgraniczeniegrneLabel = uilabel(app.DanewejciowePanel);
            app.OgraniczeniegrneLabel.Text = 'Ograniczenie górne';
            app.IloagentwLabel = uilabel(app.DanewejciowePanel);
            app.IloagentwLabel.Text = 'Ilość agentów';
            app.IloiteracjiLabel = uilabel(app.DanewejciowePanel);
            app.IloiteracjiLabel.Text = 'Ilość iteracji';
            app.dimEditFieldLabel = uilabel(app.DanewejciowePanel);
            app.dimEditFieldLabel.Text = '(dim)';
            app.dimEditField = uieditfield(app.DanewejciowePanel, 'numeric');
            app.x_minLabel = uilabel(app.DanewejciowePanel);
            app.x_minLabel.Text = '(x_min)';
            app.x_minEditField = uieditfield(app.DanewejciowePanel, 'numeric');
            app.x_maxLabel = uilabel(app.DanewejciowePanel);
            app.x_maxLabel.Text = '(x_max)';
            app.x_maxEditField = uieditfield(app.DanewejciowePanel, 'numeric');
            app.NEditFieldLabel = uilabel(app.DanewejciowePanel);
            app.NEditFieldLabel.Text = '(N)';
            app.NEditField = uieditfield(app.DanewejciowePanel, 'numeric');
            app.iterEditFieldLabel = uilabel(app.DanewejciowePanel);
            app.iterEditFieldLabel.Text = '(iter)';
            app.iterEditField = uieditfield(app.DanewejciowePanel, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end